classdef CSIVMonitor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        AboutMenu                  matlab.ui.container.Menu
        GridLayout                 matlab.ui.container.GridLayout
        subjectName                matlab.ui.control.Label
        GridLayout2                matlab.ui.container.GridLayout
        Image                      matlab.ui.control.Image
        brBPM                      matlab.ui.control.Label
        BRGraph                    matlab.ui.control.UIAxes
        GridLayout3                matlab.ui.container.GridLayout
        VitalSignMonitorLabel      matlab.ui.control.Label
        GridLayout4                matlab.ui.container.GridLayout
        InstitutodeIngenieraLabel  matlab.ui.control.Label
        UniversidadAutnomadeBajaCaliforniaLabel  matlab.ui.control.Label
        statusLabel                matlab.ui.control.Label
        GridLayout5                matlab.ui.container.GridLayout
        Image2                     matlab.ui.control.Image
        hrBPM                      matlab.ui.control.Label
        UIAxes_2                   matlab.ui.control.UIAxes
        startButton                matlab.ui.control.Button
    end

    
    properties (Access = private)
        server %TCPServer
        client % Description
        serverStatus % Description
        csi_entry % Description
        broken_perm % Description
        triangle % Description
        csi_phase_matrix % Description
        breathingEstimator % Description
        csiForCalibration % Description
        csiCalibrated % Description
        svm % Description
    end
    
    methods (Access = private)
        
        function handleMessages(app)
            index = -1;    
            %axis([1,500,-10,10]);
            t1=0;
            m1=zeros(30,1);
            p = plot(app.BRGraph,t1,m1,'MarkerSize',5);
            %Función que lee los mensajes provenientes del socket
            while app.serverStatus == 1
                drawnow; 
                %while app.server.connection.Status == "open"
                % Read size and code from the received packets
                s = warning('error', 'instrument:fread:unsuccessfulRead');
                try
                    field_len = fread(app.server.connection, 1, 'uint16');
                catch
                    warning(s);
                    app.statusLabel.Text = 'Status: Timeout, please restart the server';
                    app.closeServer(); 
                end 
                code = fread(app.server.connection,1);
                % If unhandled code, skip (seek over) the record and continue
                if (code == 187) % get beamforming or phy data
                    bytes = fread(app.server.connection, field_len-1, 'uint8');
                    bytes = uint8(bytes);
                    if (length(bytes) ~= field_len-1)
                        app.closeServer();
                    end
                else if field_len <= app.server.connection.InputBufferSize  % skip all other info
                    fread(app.server.connection, field_len-1, 'uint8');
                    continue;
                    else
                        continue;
                    end
                end
                if (code == 187) % (tips: 187 = hex2dec('bb')) Beamforming matrix -- output a record
                    app.csi_entry = read_bfee(bytes);
                
                    perm = app.csi_entry.perm;
                    Nrx = app.csi_entry.Nrx;
                    if Nrx > 1 % No permuting needed for only 1 antenna
                        if sum(perm) ~= app.triangle(Nrx) % matrix does not contain default values
                            if app.broken_perm == 0
                                app.broken_perm = 1;
                                fprintf('WARN ONCE: Found CSI (%s) with Nrx=%d and invalid perm=[%s]\n', filename, Nrx, int2str(perm));
                            end
                        else
                            app.csi_entry.csi(:,perm(1:Nrx),:) = app.csi_entry.csi(:,1:Nrx,:);
                        end
                    end
                end
                csi = get_scaled_csi(app.csi_entry);
                %app.csi_phase_matrix = angle(squeeze(csi));
                app.csi_phase_matrix = db(abs(squeeze(csi)));
                %Las lecturas recibidas se van almacenando en otra matriz
                %para su posterior calibracion 
                app.csiForCalibration = [app.csiForCalibration;app.csi_phase_matrix(1,:) app.csi_phase_matrix(2,:) app.csi_phase_matrix(3,:)];
                [numCalibEl,~] = size(app.csiForCalibration);
                %Una vez se tienen 20 segundos de muestras (25*20)
                if numCalibEl == 500
                    app.breathingEstimator.setCSIData(app.csiForCalibration);               
                    [classEstimated,app.csiCalibrated] = app.breathingEstimator.classify();
                    %plot(app.csiCalibrated);
                    set(p,'XData',[1:numCalibEl],'YData', app.csiCalibrated(:,1),'color','r','linestyle','-');     
                    %axis([1,numCalibEl,-10,10]);       
                    app.csiForCalibration(1:25,:) = [];
                    brBPMText = sprintf("%d BPM",classEstimated); 
                    app.brBPM.Text = brBPMText;
                    drawnow;
                end 
                app.csi_entry = [];
            end
        end 
        
        function closeServer(app)
            app.serverStatus = 0;  
            app.startButton.Text = "Start Server";
            app.startButton.Enable = "off";
            drawnow; 
            app.server.closeConnection(); 
            app.statusLabel.Text = "Status: Not connected";
            app.startButton.Enable = "on";
        end
        
        function startServer(app)
            app.server = TCPServer('0.0.0.0',8090,1024,60);
            app.serverStatus = 1; 
            app.startButton.Text = "Close Server";
            app.statusLabel.Text = 'Status: Waiting for client...';
            app.startButton.Enable = "off"; 
            drawnow;
            app.server.connect();
            app.startButton.Enable = "on";
            statusText = sprintf("Status: Connected with %s",app.server.connection.RemoteHost);
            app.statusLabel.Text = statusText;
            app.handleMessages(); 
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.serverStatus = 0; %el server comienza apagado 
            app.statusLabel.Text = 'Status: Not connected'; 
            app.csi_entry = []; 
            app.broken_perm = 0; 
            app.triangle = [1 3 6];
            app.BRGraph.XLabel.String = 'Subcarrier index';
            app.BRGraph.YLabel.String = 'Phase Shift';
            net = load ('netResults.mat');
            net = net.results.net2;
            %Se cargan datos previos a la red neuronal
            prevData = readtable('datasetRespiracion.csv');
            prevData = prevData{:,:};
            X = tonndata(prevData(:,1:281),false,false);
            Y = tonndata(prevData(:,282),false,false);
            [ai,ab,ac] = preparets(net, X, {},Y);
            [y1,xf,af] = net(ai,ab,ac);
            [net,xi,ai] = closeloop(net,xf,af);
            app.breathingEstimator = BreathingEstimator(net,0.15,0.4,xi,ai);
        end

        % Button pushed function: startButton
        function startButtonPushed(app, event)
            %Al presionar el boton estando en estado 0 levantara el
            %webserver
            if app.serverStatus == 0
                app.startServer(); 
            else
                app.closeServer();
            end 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AboutMenu = uimenu(app.UIFigure);
            app.AboutMenu.Text = 'About';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.subjectName = uilabel(app.GridLayout);
            app.subjectName.Text = '    Subject: Albany Armenta';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.Image = uiimage(app.GridLayout2);
            app.Image.ImageSource = 'bi_icon_breath.png';
            app.brBPM = uilabel(app.GridLayout2);
            app.brBPM.Text = '15 BPM';
            app.BRGraph = uiaxes(app.GridLayout2);
            title(app.BRGraph, 'Title')
            xlabel(app.BRGraph, 'X')
            ylabel(app.BRGraph, 'Y')
            zlabel(app.BRGraph, 'Z')
            app.BRGraph.YTick = [-0.5 -0.25 0 0.25 0.5];
            app.BRGraph.YTickLabel = {'-0.5'; '-0.25'; '0'; '0.25'; '0.5'};
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.VitalSignMonitorLabel = uilabel(app.GridLayout3);
            app.VitalSignMonitorLabel.Text = {'Vital Sign Monitor'; ''};
            app.GridLayout4 = uigridlayout(app.GridLayout);
            app.InstitutodeIngenieraLabel = uilabel(app.GridLayout4);
            app.InstitutodeIngenieraLabel.Text = 'Instituto de Ingeniería';
            app.UniversidadAutnomadeBajaCaliforniaLabel = uilabel(app.GridLayout4);
            app.UniversidadAutnomadeBajaCaliforniaLabel.Text = 'Universidad Autónoma de Baja California';
            app.statusLabel = uilabel(app.GridLayout);
            app.statusLabel.Text = 'Status';
            app.GridLayout5 = uigridlayout(app.GridLayout);
            app.Image2 = uiimage(app.GridLayout5);
            app.Image2.ImageSource = 'bi_icon_heart.png';
            app.hrBPM = uilabel(app.GridLayout5);
            app.hrBPM.Text = '60 BPM';
            app.UIAxes_2 = uiaxes(app.GridLayout5);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.startButton = uibutton(app.GridLayout, 'push');
            app.startButton.ButtonPushedFcn = createCallbackFcn(app, @startButtonPushed, true);
            app.startButton.Text = 'Start Server';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CSIVMonitor

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end